classdef FilePreviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ColormapLabel      matlab.ui.control.Label
        Switch             matlab.ui.control.Switch
        FramesEditField    matlab.ui.control.NumericEditField
        FramesSlider       matlab.ui.control.Slider
        FramesSliderLabel  matlab.ui.control.Label
        SelectDataButton   matlab.ui.control.Button
        DataUIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp; % Parent app
        filePath;
        Stack; % Image stack
        isColormapOn;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1)
            app.CallingApp = STICSgui1;
            axis(app.DataUIAxes, 'equal');
            app.DataUIAxes.XColor = 'w';
            app.DataUIAxes.YColor = 'w';
            app.isColormapOn = app.Switch.Value;
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            data = [app.filePath ch1name '.tif'];
            
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            %size(app.Stack, 3)
            %size(app.Stack, 2)
            
            app.FramesSlider.Limits = [1, size(app.Stack, 3)];
            
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,1);
            else
                file = ind2rgb(app.Stack(:,:,1), jet(256));
            end
            
            imshow(file, [100, 1000], 'Parent', app.DataUIAxes);
            %colormap(jet(256));
            %colorbar;
     
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                app.SelectDataButton.BackgroundColor = [0.33 0.77 0.01];
                app.FramesSlider.Enable = 'On';
                app.FramesSliderLabel.Enable = 'On';
                app.FramesEditField.Enable = 'On';
                app.Switch.Enable = 'On';
                
            % File invalid
            else
                % Disable adjustable fields
                app.SelectDataButton.BackgroundColor = [1.0 0.0 0.0];
                app.FramesSlider.Enable = 'Off';
                app.FramesSliderLabel.Enable = 'Off';
                app.FramesEditField.Enable = 'Off';
                app.Switch.Enable = 'Off';
            end
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            % Force value to round up
            changingValue = ceil(event.Value);
            
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,changingValue);
            else
                file = ind2rgb(app.Stack(:,:,changingValue), jet(256));
            end
            
            imshow(file, [100, 900], 'Parent', app.DataUIAxes);
            % imshow(app.Stack(:,:,changingValue), [100, 1000], 'Parent', app.DataUIAxes);
            % hold on;
            %{
            rectangle(app.DataUIAxes, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.DataUIAxes, 'Position', [((app.XIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               ((app.YIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               app.Opt.ROIsize, app.Opt.ROIsize]);
            %}
            
            % Adjust FramesEditField
            if (app.FramesEditField.Value ~= changingValue)
                app.FramesEditField.Value = changingValue;
            end
            
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = app.FramesEditField.Value;
            
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,value);
            else
                file = ind2rgb(app.Stack(:,:,value), jet(256));
            end
            
            imshow(file, [100, 900], 'Parent', app.DataUIAxes);
            
            % Adjust slider value
            if (app.FramesSlider.Value ~= value)
                app.FramesSlider.Value = value;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.isColormapOn = value;
            
            if (strcmp(value, 'On'))
                imshow(ind2rgb(app.Stack(:,:,ceil(app.FramesSlider.Value)), jet(256)), [100, 900], 'Parent', app.DataUIAxes);
            else
                imshow(app.Stack(:,:,ceil(app.FramesSlider.Value)), [100, 900], 'Parent', app.DataUIAxes);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataUIAxes = uiaxes(app.UIFigure);
            app.DataUIAxes.XTick = [];
            app.DataUIAxes.YTick = [];
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Enable = 'off';
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.Enable = 'off';
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.ColormapLabel = uilabel(app.UIFigure);
            app.ColormapLabel.Text = 'Colormap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilePreviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end